classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PingMsgLabel             matlab.ui.control.Label
        TemperatureLabel         matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Gauge                    matlab.ui.control.Gauge
        ReadTelmetryButton       matlab.ui.control.Button
        ReadTemperatureButton    matlab.ui.control.Button
        CommandsLabel            matlab.ui.control.Label
        AppsLabel                matlab.ui.control.Label
        ConnectionLabel          matlab.ui.control.Label
        MotorsButton             matlab.ui.control.Button
        PingButton               matlab.ui.control.Button
        MemoryButton             matlab.ui.control.Button
        SunsensorButton          matlab.ui.control.Button
        MagnetometerButton       matlab.ui.control.Button
        GyroscopeButton          matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        UpdateSerialButton       matlab.ui.control.Button
        BaudRateEditField        matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel   matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        SerialPortDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Serialdev % the serial port to which we connect
    end
    
    methods (Access = private)
        
        function command = processcommand(app,array,dir)
            if dir == "in"
                
            else
                
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~,y]=system('wmic path win32_pnpentity get name /format:table |find "COM"');
            str = strsplit(y,'\n');
            str = strtrim(str);
            app.SerialPortDropDown.Items = str(1:end-1);
        end

        % Button pushed function: UpdateSerialButton
        function UpdateSerialButtonPushed(app, event)
            [~,y]=system('wmic path win32_pnpentity get name /format:table |find "COM"');
            str = strsplit(y,'\n');
            str = strtrim(str);
            app.SerialPortDropDown.Items = str(1:end-1);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            serialname = convertStringsToChars(extractBetween( ...
                app.SerialPortDropDown.Value,'(',')'));
            serialname = serialname{1};
            serialbaud = app.BaudRateEditField.Value;
            class(serialname)
            app.Serialdev = serialport(serialname,serialbaud);
        end

        % Button pushed function: PingButton
        function PingButtonPushed(app, event)
            command = [0x07,0x01,0x00];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.UpdateSerialButton = uibutton(app.UIFigure, 'push');
            app.UpdateSerialButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSerialButtonPushed, true);
            app.UpdateSerialButton.Text = 'Update Serial';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.GyroscopeButton = uibutton(app.UIFigure, 'push');
            app.GyroscopeButton.Text = 'Gyroscope';
            app.MagnetometerButton = uibutton(app.UIFigure, 'push');
            app.MagnetometerButton.Text = 'Magnetometer';
            app.SunsensorButton = uibutton(app.UIFigure, 'push');
            app.SunsensorButton.Text = 'Sunsensor';
            app.MemoryButton = uibutton(app.UIFigure, 'push');
            app.MemoryButton.Text = 'Memory';
            app.PingButton = uibutton(app.UIFigure, 'push');
            app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
            app.PingButton.Text = 'Ping';
            app.MotorsButton = uibutton(app.UIFigure, 'push');
            app.MotorsButton.Text = 'Motors';
            app.ConnectionLabel = uilabel(app.UIFigure);
            app.ConnectionLabel.Text = 'Connection';
            app.AppsLabel = uilabel(app.UIFigure);
            app.AppsLabel.Text = 'Apps';
            app.CommandsLabel = uilabel(app.UIFigure);
            app.CommandsLabel.Text = 'Commands';
            app.ReadTemperatureButton = uibutton(app.UIFigure, 'push');
            app.ReadTemperatureButton.Text = 'Read Temperature';
            app.ReadTelmetryButton = uibutton(app.UIFigure, 'push');
            app.ReadTelmetryButton.Text = 'Read Telmetry';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.PingMsgLabel = uilabel(app.UIFigure);
            app.PingMsgLabel.Text = 'Ping Msg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end